classdef dynamixel_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InitializationButton     matlab.ui.control.Button
        SearchSerialPortButton   matlab.ui.control.Button
        SerialPortDropDownLabel  matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        TorqueEnableButton       matlab.ui.control.StateButton
        robotHomeButton          matlab.ui.control.Button
        J2SliderLabel            matlab.ui.control.Label
        J2Slider                 matlab.ui.control.Slider
        J3SliderLabel            matlab.ui.control.Label
        J3Slider                 matlab.ui.control.Slider
        VelocitySliderLabel      matlab.ui.control.Label
        VelocitySlider           matlab.ui.control.Slider
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %      PRIVATE PROPERTIES       %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = private)
        %% AUXILIAR %%
        bIni = 0;   % Boolean variable         
        
        %% DYNAMIXEL %%
        lib_name = 'dxl_x64_c';
        port_num;
        groupwrite_num;
        groupread_num;
        dxl_comm_result;           % Communication result
        dxl_addparam_result;       % AddParam result
        dxl_getdata_result;        % GetParam result
        dxl1_goal_position;        % Goal position  
        dxl2_goal_position;         
        dxl_error;                 % Dynamixel error
        dxl1_present_position;     % Present position
        dxl2_present_position;
        
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %      PUBLIC PROPERTIES       %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = public)
        %% EEPROM CONTROL TABLE %%
        ADD_OPERATING_MODE = 11;
        ADD_MOVING_THRESHOLD = 24;
        ADD_TEMPERATURE_LIMIT = 31;
        ADD_MAX_VOLTAGE_LIMIT = 32;
        ADD_MIN_VOLTAGE_LIMIT = 34;
        ADD_PWM_LIMIT = 36;
        ADD_CURRENT_LIMIT = 38;
        ADD_VELOCITY_LIMIT = 44;
        ADD_MAX_POSITION_LIMIT = 48;
        ADD_MIN_POSITION_LIMIT = 52;
        
        %% RAM CONTROL TABLE %%
        ADD_TORQUE_ENABLE = 64;
        ADD_LED = 65;
        ADD_VELOCITY_I_GAIN = 76;
        ADD_VELOCITY_P_GAIN = 78;
        ADD_POSITION_D_GAIN = 80;
        ADD_POSITION_I_GAIN = 82;
        ADD_POSITION_P_GAIN = 84;
        ADD_FEEDFORWARD_2ND_GAIN = 88;
        ADD_FEEDFORWARD_1ST_GAIN = 90;
        ADD_GOAL_PWM = 100;
        ADD_GOAL_CURRENT = 102;
        ADD_GOAL_VELOCITY = 104;
        ADD_PROFILE_ACCELERATION = 108;
        ADD_PROFILE_VELOCITY = 112;
        ADD_GOAL_POSITION = 116;
        ADD_REALTIME_TICK = 120;
        ADD_MOVING = 122;
        ADD_PRESENT_PWM = 124;
        ADD_PRESENT_CURRENT = 126;
        ADD_PRESENT_VELOCITY = 128;
        ADD_PRESENT_POSITION = 132;
        ADD_PRESENT_TEMPERATURE = 146;
        
        % Byte length
        LEN_GOAL_POSITION       = 4;
        LEN_PRESENT_POSITION    = 4;
        
        % Protocol version
        PROTOCOL_VERSION            = 2.0;          % See which protocol version is used in the Dynamixel
                
        % DEFAULT SETTING
        DXL1_ID                     = 1;            % Dynamixel#1 ID: 1
        DXL2_ID                     = 2;            % Dynamixel#2 ID: 2
        BAUDRATE                    = 1000000;
        DEVICENAME                  = 'COM1';  
        
        TORQUE_ENABLE               = 1;            % Value for enabling the torque
        TORQUE_DISABLE              = 0;            % Value for disabling the torque
        DXL_MINIMUM_POSITION_VALUE  = 736;            % Dynamixel will rotate between this value
        DXL_MAXIMUM_POSITION_VALUE  = 3360;         % and this value (note that the Dynamixel would not move when the position value is out of movable range. Check e-manual about the range of the Dynamixel you use.)
        DXL_MOVING_STATUS_THRESHOLD = 20;           % Dynamixel moving status threshold
        
        COMM_SUCCESS                = 0;            % Communication Success result value
        COMM_TX_FAIL                = -1001;        % Communication Tx Failed
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %       PUBLIC FUNCTIONS        %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = public)
        
        function initialization(app)
            % If the robot hasn't been intialized
            if ~app.bIni
                % Load library
                if ~libisloaded(app.lib_name)
                    [notfound, warnings] = loadlibrary(app.lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h', 'addheader', 'group_sync_read.h');
                    assignin('base', 'errors_library', [notfound warnings]);
                end
                
                % Load Control table
                app.BAUDRATE = 1000000;
                app.DEVICENAME =  app.SerialPortDropDown.Items{1}; 
                assignin('base', 'DEVICENAME', app.DEVICENAME);
                    
                
                % Get methods and members of PortHandlerLinux or PortHandlerWindows
                app.port_num = portHandler(app.DEVICENAME);
                
                % Initialize PacketHandler Structs
                packetHandler();
                
                % Initialize Groupsyncwrite Structs
                app.groupwrite_num = groupSyncWrite(app.port_num, app.PROTOCOL_VERSION, app.ADD_GOAL_POSITION, app.LEN_GOAL_POSITION);
                
                % Initialize Groupsyncread Structs for Present Position
                app.groupread_num = groupSyncRead(app.port_num, app.PROTOCOL_VERSION, app.ADD_PRESENT_POSITION, app.LEN_PRESENT_POSITION);
                
                
                app.dxl_comm_result = app.COMM_TX_FAIL;           % Communication result
                app.dxl_addparam_result = false;                  % AddParam result
                app.dxl_getdata_result = false;                   % GetParam result
                app.dxl1_goal_position = (app.DXL_MAXIMUM_POSITION_VALUE+app.DXL_MINIMUM_POSITION_VALUE)/2;         % Initial position
                app.dxl2_goal_position = app.dxl1_goal_position;
                
                app.dxl_error = 0;                              % Dynamixel error
                app.dxl1_present_position = 0;                  % Present position
                app.dxl2_present_position = 0;
                
                
                % Open port
                if (openPort(app.port_num))
                    fprintf('Succeeded to open the port!\n');
                else
                    unloadlibrary(app.lib_name);
                    fprintf('Failed to open the port!\n');
                    input('Press any key to terminate...\n');
                    return;
                end  
                
                % Set port baudrate
                if (setBaudRate(app.port_num, app.BAUDRATE))
                    fprintf('Succeeded to change the baudrate!\n');
                else
                    unloadlibrary(app.lib_name);
                    fprintf('Failed to change the baudrate!\n');
                    input('Press any key to terminate...\n');
                    return;
                end
                            
                % Enable Dynamixel Torque
                enableTorque(app,app.DXL1_ID);
                enableTorque(app,app.DXL2_ID);
                app.TorqueEnableButton.Value = 1;
                
                % Move to Home Position
                moveDxl(app,app.DXL1_ID,app.dxl1_goal_position)
                moveDxl(app,app.DXL2_ID,app.dxl2_goal_position)
                              
            end
            % Update initialization state
            app.bIni = 1;
        end

    end
    
    methods (Access = private)
        
        function enableTorque(app,dxl_ID)
            % Enable Dynamixel#1 Torque
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, dxl_ID, app.ADD_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel #%d has been successfully connected \n', app.DXL1_ID);
            end
        end
        
        function disableTorque(app,dxl_ID)
            % Enable Dynamixel#1 Torque
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, dxl_ID, app.ADD_TORQUE_ENABLE, app.TORQUE_DISABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel #%d has been successfully connected \n', app.DXL1_ID);
            end
        end
        
        function moveDxl(app,dxl_ID, goal_pos)
            % Add Dynamixel#1 goal position value to the Syncwrite storage
            app.dxl_addparam_result = groupSyncWriteAddParam(app.groupwrite_num, dxl_ID, typecast(int32(goal_pos), 'uint32'), app.LEN_GOAL_POSITION);
            if app.dxl_addparam_result ~= true
                fprintf('[ID:%03d] groupSyncWrite addparam failed', app.dxl_ID);
                return;
            end
        
            % Syncwrite goal position
            groupSyncWriteTxPacket(app.groupwrite_num);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            end
        
            % Clear syncwrite parameter storage
            groupSyncWriteClearParam(app.groupwrite_num);
            
            if dxl_ID == 1
                app.J2Slider.Value = (goal_pos-2048)*360/4096;
            elseif dxl_ID == 2
                app.J3Slider.Value = (goal_pos-2048)*360/4096;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
            initialization(app);
        end

        % Button pushed function: SearchSerialPortButton
        function SearchSerialPortButtonPushed(app, event)
            % Get the list of connected devices
            app.SerialPortDropDown.Items = seriallist;
        end

        % Value changed function: TorqueEnableButton
        function TorqueEnableButtonValueChanged(app, event)
            if app.TorqueEnableButton.Value
                enableTorque(app,app.DXL1_ID);
                enableTorque(app,app.DXL2_ID);
            else
                disableTorque(app,app.DXL1_ID);
                disableTorque(app,app.DXL2_ID);
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disable Torque
            disableTorque(app,app.DXL1_ID);
            disableTorque(app,app.DXL2_ID);
            app.TorqueEnableButton.Value = 1;
        
            % Close port
            closePort(app.port_num);
            
            % Unload Library
            unloadlibrary(app.lib_name);

            delete(app) 
        end

        % Button pushed function: robotHomeButton
        function robotHomeButtonPushed(app, event)
            % Enable Torque
            enableTorque(app,app.DXL1_ID);
            enableTorque(app,app.DXL2_ID);
            
            % Set home position
            app.dxl1_goal_position = (app.DXL_MAXIMUM_POSITION_VALUE+app.DXL_MINIMUM_POSITION_VALUE)/2;         % Initial position
            app.dxl2_goal_position = app.dxl1_goal_position;
            
            % Move to home Position
            moveDxl(app,app.DXL1_ID,app.dxl1_goal_position)
            moveDxl(app,app.DXL2_ID,app.dxl2_goal_position)
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            if app.bIni
                % Enable Torque
                enableTorque(app,app.DXL1_ID);
                
                % Set Joint 2 position
                app.dxl1_goal_position = app.J2Slider.Value*4096/360 + 2048;
                
                % Move Joint 2
                moveDxl(app,app.DXL1_ID,app.dxl1_goal_position)
            end            
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            if app.bIni
                % Enable Torque
                enableTorque(app,app.DXL2_ID);
                
                % Set Joint 2 position
                app.dxl2_goal_position = app.J3Slider.Value*4096/360 + 2048;
                
                % Move Joint 2
                moveDxl(app,app.DXL2_ID,app.dxl2_goal_position)
            end                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InitializationButton = uibutton(app.UIFigure, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Text = 'Initialization';
            app.SearchSerialPortButton = uibutton(app.UIFigure, 'push');
            app.SearchSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @SearchSerialPortButtonPushed, true);
            app.SearchSerialPortButton.Text = 'Search Serial Port';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.TorqueEnableButton = uibutton(app.UIFigure, 'state');
            app.TorqueEnableButton.ValueChangedFcn = createCallbackFcn(app, @TorqueEnableButtonValueChanged, true);
            app.TorqueEnableButton.Text = 'Torque Enable';
            app.robotHomeButton = uibutton(app.UIFigure, 'push');
            app.robotHomeButton.ButtonPushedFcn = createCallbackFcn(app, @robotHomeButtonPushed, true);
            app.robotHomeButton.Text = 'robotHome';
            app.J2SliderLabel = uilabel(app.UIFigure);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.UIFigure);
            app.J2Slider.Limits = [-115.3 115.3];
            app.J2Slider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.J2Slider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J2Slider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.J2Slider.Value = 115.3;
            app.J3SliderLabel = uilabel(app.UIFigure);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.UIFigure);
            app.J3Slider.Limits = [-115.3 115.3];
            app.J3Slider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.J3Slider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.J3Slider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.J3Slider.Value = 115.3;
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynamixel_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end