classdef robotControl_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RobotControlAppLabel         matlab.ui.control.Label
        BasicsPanel                  matlab.ui.container.Panel
        InitializationButton         matlab.ui.control.Button
        TorqueEnableButton           matlab.ui.control.StateButton
        robotHomeButton              matlab.ui.control.Button
        SearchPortsButton            matlab.ui.control.Button
        DXLPortLabel                 matlab.ui.control.Label
        DXLPortDropDown              matlab.ui.control.DropDown
        ArduinoVSLDropDownLabel      matlab.ui.control.Label
        ArduinoVSLDropDown           matlab.ui.control.DropDown
        StatusLabel                  matlab.ui.control.Label
        StatusLabel_2                matlab.ui.control.Label
        ArduinoStepperDropDownLabel  matlab.ui.control.Label
        ArduinoStepperDropDown       matlab.ui.control.DropDown
        RecorddataButton             matlab.ui.control.Button
        JointsPositionPanel          matlab.ui.container.Panel
        J2SliderLabel                matlab.ui.control.Label
        J2Slider                     matlab.ui.control.Slider
        J3SliderLabel                matlab.ui.control.Label
        J3Slider                     matlab.ui.control.Slider
        J1SliderLabel                matlab.ui.control.Label
        J1Slider                     matlab.ui.control.Slider
        JointsPWMPanel               matlab.ui.container.Panel
        J2PWMSliderLabel             matlab.ui.control.Label
        J2PWMSlider                  matlab.ui.control.Slider
        J3PWMSliderLabel             matlab.ui.control.Label
        J3PWMSlider                  matlab.ui.control.Slider
        LinksPressurebarPanel        matlab.ui.container.Panel
        VSL1SliderLabel              matlab.ui.control.Label
        VSL1Slider                   matlab.ui.control.Slider
        VSL2SliderLabel              matlab.ui.control.Label
        VSL2Slider                   matlab.ui.control.Slider
        J1VelocitySliderLabel        matlab.ui.control.Label
        J1VelocitySlider             matlab.ui.control.Slider
        CartesianPositionmmPanel     matlab.ui.container.Panel
        YSliderLabel                 matlab.ui.control.Label
        YSlider                      matlab.ui.control.Slider
        ZSliderLabel                 matlab.ui.control.Label
        ZSlider                      matlab.ui.control.Slider
        XSliderLabel                 matlab.ui.control.Label
        XSlider                      matlab.ui.control.Slider
        Valve1Button                 matlab.ui.control.StateButton
        Valve2Button                 matlab.ui.control.StateButton
        CartesianpositionEditFieldLabel  matlab.ui.control.Label
        CartesianpositionEditField   matlab.ui.control.EditField
        MoveButton                   matlab.ui.control.Button
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %       PUBLIC PROPERTIES       %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = public)
        %% AUXILIAR %%
        bIni = 0;                   % Boolean variable - System initialized
        monitor_figure;             % Figure name of the robotMonitor app
        bMonitoring = 0;            % Boolean variable - Monitoring ON/OFF
        
        %% ROBOT PARAMETERS %%
        L1 = 79.9;                  % Distance from the base to the first joint
        L2 = 255.5;                 % Distance from the first to the second joint
        L3 = 209.5;                 % Distance from the second joint to the end-effector
        
        %% DYNAMIXEL %%
        lib_name = 'dxl_x64_c';
        dxl_port_num;
        groupwrite_num;
        groupread_num;
        dxl_comm_result;           % Communication result
        dxl_addparam_result;       % AddParam result
        dxl_getdata_result;        % GetParam result
        dxl1_goal_position;        % Goal position
        dxl2_goal_position;
        dxl_error;                 % Dynamixel error
        dxl1_present_position;     % Present position
        dxl2_present_position;
        
        %% ARDUINO %%
        arduinoSerial_pressure;             % Arduino Pressure Serial
        arduinoSerial_stepper;              % Arduino Stepper Serial
        VSL1FeedbackPin = 'A0'              % VSL1 pressure feedback          
        VSL2FeedbackPin = 'A1'              % VSL2 pressure feedback
        VSL1CommandPin = 'D6'               % VSL1 pressure command         
        VSL2CommandPin = 'D7'               % VSL2 pressure command
        J1DirPin = 'D5';                    % Stepper Motor direction
        J1StepPin = 'D6';                   % Stepper Motor step
        J1_current_position;                % Current position in degrees of J1
        J1_goal_position;                   % Goal position in degrees of J1
        J1_velocity;                        % Velocity of J1
        J1_direction;                       % Direction of J1
        valve1;                             % Status of valve1
        valve2;                             % Status of valve2
        
        % Protocol version
        PROTOCOL_VERSION            = 2.0;          % See which protocol version is used in the Dynamixel
        
        % DEFAULT SETTING
        DXL1_ID                     = 1;            % Dynamixel#1 ID: 1
        DXL2_ID                     = 2;            % Dynamixel#2 ID: 2
        BAUDRATE                    = 1000000;
        DEVICENAME                  = 'COM1';
        
        TORQUE_ENABLE               = 1;            % Value for enabling the torque
        TORQUE_DISABLE              = 0;            % Value for disabling the torque
        DXL_MINIMUM_POSITION_VALUE  = 736;          % minimum position limit
        DXL_MAXIMUM_POSITION_VALUE  = 3360;         % maximum position limit
        DXL_MOVING_STATUS_THRESHOLD = 20;           % Dynamixel moving status threshold
        DXL_MINIMUM_PWM_VALUE  = 0;                 % minimum pwm limit
        DXL_MAXIMUM_PWM_VALUE  = 885;               % maximum pwm limit
        
        COMM_SUCCESS                = 0;            % Communication Success result value
        COMM_TX_FAIL                = -1001;        % Communication Tx Failed
    end
    
    
    properties (Constant)
        %% EEPROM CONTROL TABLE %%
        ADD_OPERATING_MODE = 11;
        ADD_MOVING_THRESHOLD = 24;
        ADD_TEMPERATURE_LIMIT = 31;
        ADD_MAX_VOLTAGE_LIMIT = 32;
        ADD_MIN_VOLTAGE_LIMIT = 34;
        ADD_PWM_LIMIT = 36;
        ADD_CURRENT_LIMIT = 38;
        ADD_VELOCITY_LIMIT = 44;
        ADD_MAX_POSITION_LIMIT = 48;
        ADD_MIN_POSITION_LIMIT = 52;
        
        %% RAM CONTROL TABLE %%
        ADD_TORQUE_ENABLE = 64;
        ADD_LED = 65;
        ADD_VELOCITY_I_GAIN = 76;
        ADD_VELOCITY_P_GAIN = 78;
        ADD_POSITION_D_GAIN = 80;
        ADD_POSITION_I_GAIN = 82;
        ADD_POSITION_P_GAIN = 84;
        ADD_FEEDFORWARD_2ND_GAIN = 88;
        ADD_FEEDFORWARD_1ST_GAIN = 90;
        ADD_GOAL_PWM = 100;
        ADD_GOAL_CURRENT = 102;
        ADD_GOAL_VELOCITY = 104;
        ADD_PROFILE_ACCELERATION = 108;
        ADD_PROFILE_VELOCITY = 112;
        ADD_GOAL_POSITION = 116;
        ADD_REALTIME_TICK = 120;
        ADD_MOVING = 122;
        ADD_PRESENT_PWM = 124;
        ADD_PRESENT_CURRENT = 126;
        ADD_PRESENT_VELOCITY = 128;
        ADD_PRESENT_POSITION = 132;
        ADD_PRESENT_TEMPERATURE = 146;
        
        % Byte length
        LEN_GOAL_POSITION       = 4;
        LEN_PRESENT_POSITION    = 4;
    end
    
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %       PUBLIC FUNCTIONS        %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = public)
        
        function initialization(app)
            
            % If the robot hasn't been intialized
            if ~app.bIni
                % Load library
                if ~libisloaded(app.lib_name)
                    [notfound, warnings] = loadlibrary(app.lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h', 'addheader', 'group_sync_read.h');
                    assignin('base', 'errors_library', [notfound warnings]);
                end
                
                % Load Control table
                app.BAUDRATE = 1000000;
                app.DEVICENAME =  app.DXLPortDropDown.Value;
                
                % Get methods and members of PortHandlerLinux or PortHandlerWindows
                app.dxl_port_num = portHandler(app.DEVICENAME);
                
                % Initialize PacketHandler Structs
                packetHandler();
                
                % Initialize Groupsyncwrite Structs
                app.groupwrite_num = groupSyncWrite(app.dxl_port_num, app.PROTOCOL_VERSION, app.ADD_GOAL_POSITION, app.LEN_GOAL_POSITION);
                
                % Initialize Groupsyncread Structs for Present Position
                app.groupread_num = groupSyncRead(app.dxl_port_num, app.PROTOCOL_VERSION, app.ADD_PRESENT_POSITION, app.LEN_PRESENT_POSITION);
                
                app.dxl_comm_result = app.COMM_TX_FAIL;           % Communication result
                app.dxl_addparam_result = false;                  % AddParam result
                app.dxl_getdata_result = false;                   % GetParam result
                app.dxl1_goal_position = (app.DXL_MAXIMUM_POSITION_VALUE+app.DXL_MINIMUM_POSITION_VALUE)/2;         % Initial position
                app.dxl2_goal_position = app.dxl1_goal_position;
                
                app.dxl_error = 0;                              % Dynamixel error
                app.dxl1_present_position = 0;                  % Present position
                app.dxl2_present_position = 0;
                
                
                % Open port
                if (openPort(app.dxl_port_num))
                    app.StatusLabel_2.Text = 'Succeeded to open DXL port';
                else
                    unloadlibrary(app.lib_name);
                    app.StatusLabel_2.Text = 'Failed to open DXL port';
                end
                
                % Set port baudrate
                if (setBaudRate(app.dxl_port_num, app.BAUDRATE))
                    fprintf('Succeeded to change the baudrate!\n');
                else
                    unloadlibrary(app.lib_name);
                    fprintf('Failed to change the baudrate!\n');
                    input('Press any key to terminate...\n');
                    return;
                end
                
                % Enable Dynamixel Torque
                enableTorque(app,app.DXL1_ID);
                enableTorque(app,app.DXL2_ID);
                app.TorqueEnableButton.Value = 1;
                
                % Set PWM to 10%
                initial_PWM = 0.10*app.DXL_MAXIMUM_PWM_VALUE;
                setPWM(app,app.DXL1_ID,initial_PWM);
                setPWM(app,app.DXL2_ID,initial_PWM);
                
                % Move to Home Position
                moveDxl(app,app.DXL1_ID,app.dxl1_goal_position)
                moveDxl(app,app.DXL2_ID,app.dxl2_goal_position)
                
                % Create the arduino pressure object
                app.arduinoSerial_pressure  = serial(app.ArduinoVSLDropDown.Value);
                app.arduinoSerial_pressure.BaudRate = 115200;
                fopen(app.arduinoSerial_pressure);
                
                % Create the arduino serial object for the stepper motor                
                app.arduinoSerial_stepper = serial(app.ArduinoStepperDropDown.Value);
                app.arduinoSerial_stepper.BaudRate = 115200;
                fopen(app.arduinoSerial_stepper);
                
                % Set J1 velocity to 25%
                app.J1_velocity = 25;
                app.J1VelocitySlider.Value = 25;
                
                % Set J1 current position
                app.J1_current_position = 0;
                app.J1Slider.Value = 0;
                
            end
            % Update initialization state
            app.bIni = 1;
            app.StatusLabel_2.Text = 'System Ready';
        end
    end
    
    methods (Access = private)
        
        function enableTorque(app,dxl_ID)
            % Enable Dynamixel#1 Torque
            write1ByteTxRx(app.dxl_port_num, app.PROTOCOL_VERSION, dxl_ID, app.ADD_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.dxl_port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.dxl_port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel #%d has been successfully connected \n', dxl_ID);
            end
        end
        
        function disableTorque(app,dxl_ID)
            % Enable Dynamixel#1 Torque
            write1ByteTxRx(app.dxl_port_num, app.PROTOCOL_VERSION, dxl_ID, app.ADD_TORQUE_ENABLE, app.TORQUE_DISABLE);
            app.dxl_comm_result = getLastTxRxResult(app.dxl_port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.dxl_port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel #%d has been successfully connected \n', dxl_ID);
            end
        end
        
        function moveDxl(app,dxl_ID, goal_pos)
            % Add Dynamixel#1 goal position value to the Syncwrite storage
            app.dxl_addparam_result = groupSyncWriteAddParam(app.groupwrite_num, dxl_ID, typecast(int32(goal_pos), 'uint32'), app.LEN_GOAL_POSITION);
            if app.dxl_addparam_result ~= true
                fprintf('[ID:%03d] groupSyncWrite addparam failed', app.dxl_ID);
                return;
            end
            
            % Syncwrite goal position
            groupSyncWriteTxPacket(app.groupwrite_num);
            app.dxl_comm_result = getLastTxRxResult(app.dxl_port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            end
            
            % Clear syncwrite parameter storage
            groupSyncWriteClearParam(app.groupwrite_num);
            
            if dxl_ID == 1
                app.J2Slider.Value = (goal_pos-2048)*360/4096;
            elseif dxl_ID == 2
                app.J3Slider.Value = (goal_pos-2048)*360/4096;
            end
        end
        
        function setPWM(app, dxl_ID, dxl_PWM)
            % Set PWM
            write2ByteTxRx(app.dxl_port_num, app.PROTOCOL_VERSION, dxl_ID, app.ADD_GOAL_PWM, typecast(int32(dxl_PWM), 'uint32'));
            app.dxl_comm_result = getLastTxRxResult(app.dxl_port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.dxl_port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel #%d has been successfully connected \n', dxl_ID);
            end
            
            % Change the PWM's slider value
            if dxl_ID == 1
                app.J2PWMSlider.Value = dxl_PWM*100/885;
            elseif dxl_ID == 2
                app.J3PWMSlider.Value = dxl_PWM*100/885;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLabel_2.Text = 'System not connected';
            assignin('base', 'robotControl_app', app);
        end

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
            app.StatusLabel_2.Text = 'Initializing...';
            initialization(app);
        end

        % Button pushed function: SearchPortsButton
        function SearchPortsButtonPushed(app, event)
            % Get the list of connected devices
            app.DXLPortDropDown.Items = seriallist;
            app.ArduinoVSLDropDown.Items = seriallist;
            app.ArduinoStepperDropDown.Items = seriallist;
        end

        % Value changed function: TorqueEnableButton
        function TorqueEnableButtonValueChanged(app, event)
            if app.TorqueEnableButton.Value
                enableTorque(app,app.DXL1_ID);
                enableTorque(app,app.DXL2_ID);
            else
                disableTorque(app,app.DXL1_ID);
                disableTorque(app,app.DXL2_ID);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.bIni
                % Disable Torque
                disableTorque(app,app.DXL1_ID);
                disableTorque(app,app.DXL2_ID);
                app.TorqueEnableButton.Value = 1;
                
                % Close port
                closePort(app.dxl_port_num);
                fclose(app.arduinoSerial_stepper);
                fclose(app.arduinoSerial_pressure);
                
                % Unload Library
                unloadlibrary(app.lib_name);
                
                % Close Monitoring app
%                 if app.bMonitoring
%                     close(app.monitor_figure);
%                 end
            end
            delete(app)
        end

        % Button pushed function: robotHomeButton
        function robotHomeButtonPushed(app, event)
            % Enable Torque
            enableTorque(app,app.DXL1_ID);
            enableTorque(app,app.DXL2_ID);
            
            % Set home position
            app.dxl1_goal_position = (app.DXL_MAXIMUM_POSITION_VALUE+app.DXL_MINIMUM_POSITION_VALUE)/2;         % Initial position
            app.dxl2_goal_position = app.dxl1_goal_position;
            
            % Move to home Position
            moveDxl(app,app.DXL1_ID,app.dxl1_goal_position)
            moveDxl(app,app.DXL2_ID,app.dxl2_goal_position)
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            if app.bIni
                % Enable Torque
                enableTorque(app,app.DXL1_ID);
                
                % Set Joint 2 position
                app.dxl1_goal_position = app.J2Slider.Value*4096/360 + 2048;
                
                % Move Joint 2
                moveDxl(app,app.DXL1_ID,app.dxl1_goal_position)
            end
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            if app.bIni
                % Enable Torque
                enableTorque(app,app.DXL2_ID);
                
                % Set Joint 2 position
                app.dxl2_goal_position = app.J3Slider.Value*4096/360 + 2048;
                
                % Move Joint 2
                moveDxl(app,app.DXL2_ID,app.dxl2_goal_position)
            end
        end

        % Value changed function: J2PWMSlider
        function J2PWMSliderValueChanged(app, event)
            % calculate PWM (PWM goes from 0 to 885, not 0 to 100%)
            PWM = app.J2PWMSlider.Value*885/100;
            
            setPWM(app,app.DXL1_ID,PWM);
        end

        % Value changed function: J3PWMSlider
        function J3PWMSliderValueChanged(app, event)
            % calculate PWM (PWM goes from 0 to 885, not 0 to 100%)
            PWM = app.J3PWMSlider.Value*885/100;
            assignin('base', 'PWM2',PWM);
            
            setPWM(app,app.DXL2_ID,PWM);
        end

        % Value changed function: J1Slider
        function J1SliderValueChanged(app, event)
            % set the goal position of J1
            app.J1_goal_position = app.J1Slider.Value;
            
            % calculate the direction and number of steps to go from the current position
            % to the goal position
            current_J1 = str2double(fscanf(app.arduinoSerial_stepper));
            n_degrees = app.J1_goal_position - current_J1*360/3200;
            if (n_degrees > 0)
                app.J1_direction = 1;
            else
                app.J1_direction = 0;
            end
            n_steps = uint16(abs(n_degrees*3200/360));      % 3200 steps per revolution
            
            fprintf(app.arduinoSerial_stepper,mat2str([n_steps, app.J1_direction, app.J1_velocity]));
%             prev_J1 = str2double(fscanf(app.arduinoSerial_stepper));
%             
%             % Check wehter the movement is complete
%             movement_complete = 0;
%             while ~movement_complete
%                 pause(0.01);
%                 current_J1 = str2double(fscanf(app.arduinoSerial_stepper));
%                 if current_J1 == prev_J1
%                     fprintf(app.arduinoSerial_stepper,mat2str([n_steps, app.J1_direction, app.J1_velocity]));
%                 else
%                     movement_complete = 1;
%                 end
%             end
%             app.J1_current_position = app.J1Slider.Value;
        end

        % Button pushed function: RecorddataButton
        function RecorddataButtonPushed(app, event)
            % Open the robotMonitor app
            if app.bIni
                app.monitor_figure = robotMonitor;
                app.bMonitoring = 1;
            else
                app.StatusLabel_2.Text = 'Initialize the robot first';
            end
        end

        % Value changed function: J1VelocitySlider
        function J1VelocitySliderValueChanged(app, event)
            app.J1_velocity = app.J1VelocitySlider.Value;
        end

        % Value changed function: VSL1Slider
        function VSL1SliderValueChanged(app, event)
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(app.arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(app.arduinoSerial_pressure);
            end
        end

        % Value changed function: VSL2Slider
        function VSL2SliderValueChanged(app, event)
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(app.arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(app.arduinoSerial_pressure);
            end
        end

        % Value changed function: Valve1Button
        function Valve1ButtonValueChanged(app, event)
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(app.arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(app.arduinoSerial_pressure);
            end
        end

        % Value changed function: Valve2Button
        function Valve2ButtonValueChanged(app, event)
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(app.arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(app.arduinoSerial_pressure);
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            X = str2num(app.CartianpositionEditField.Value);
            if dim(X)~=3
                app.StatusLabel_2.Text = 'Invalid Poisition';
            else
                [Q_goal,status] = goto(X, app.threshold,app.arduinoSerial_stepper,aap.dxl_port_num,app.PROTOCOL_VERSION,app.ADD_PRESENT_POSITION);
                app.StatusLabel_2.Text = status;
                
                Q_current=getPosition(arduino_stepper,app.dxl_port_num,app.PROTOCOL_VERSION,app.ADD_PRESENT_POSITION);
                %Wait until the movement is completed
                while(sqrt((Q_goal(1)-Q_current(1))^2)+(Q_goal(1)-Q_current(2))^2+(Q_goal(3)-Q_current(3))^2)>threshold
                    Q_current=getPosition(arduino_stepper,app.dxl_port_num,app.PROTOCOL_VERSION,app.ADD_PRESENT_POSITION);
                end
                app.StatusLabel_2.Text = 'Completed movement';
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RobotControlAppLabel = uilabel(app.UIFigure);
            app.RobotControlAppLabel.Text = 'Robot Control App';
            app.BasicsPanel = uipanel(app.UIFigure);
            app.BasicsPanel.Title = 'Basics';
            app.InitializationButton = uibutton(app.BasicsPanel, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Text = 'Initialization';
            app.TorqueEnableButton = uibutton(app.BasicsPanel, 'state');
            app.TorqueEnableButton.ValueChangedFcn = createCallbackFcn(app, @TorqueEnableButtonValueChanged, true);
            app.TorqueEnableButton.Text = 'Torque Enable';
            app.robotHomeButton = uibutton(app.BasicsPanel, 'push');
            app.robotHomeButton.ButtonPushedFcn = createCallbackFcn(app, @robotHomeButtonPushed, true);
            app.robotHomeButton.Text = 'robotHome';
            app.SearchPortsButton = uibutton(app.BasicsPanel, 'push');
            app.SearchPortsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchPortsButtonPushed, true);
            app.SearchPortsButton.Text = 'Search Ports';
            app.DXLPortLabel = uilabel(app.BasicsPanel);
            app.DXLPortLabel.Text = 'DXL Port';
            app.DXLPortDropDown = uidropdown(app.BasicsPanel);
            app.DXLPortDropDown.Items = {};
            app.DXLPortDropDown.Value = {};
            app.ArduinoVSLDropDownLabel = uilabel(app.BasicsPanel);
            app.ArduinoVSLDropDownLabel.Text = 'Arduino VSL';
            app.ArduinoVSLDropDown = uidropdown(app.BasicsPanel);
            app.ArduinoVSLDropDown.Items = {};
            app.ArduinoVSLDropDown.Value = {};
            app.StatusLabel = uilabel(app.BasicsPanel);
            app.StatusLabel.Text = 'Status:';
            app.StatusLabel_2 = uilabel(app.BasicsPanel);
            app.StatusLabel_2.Text = 'Status';
            app.ArduinoStepperDropDownLabel = uilabel(app.BasicsPanel);
            app.ArduinoStepperDropDownLabel.Text = 'Arduino Stepper';
            app.ArduinoStepperDropDown = uidropdown(app.BasicsPanel);
            app.ArduinoStepperDropDown.Items = {};
            app.ArduinoStepperDropDown.Value = {};
            app.RecorddataButton = uibutton(app.BasicsPanel, 'push');
            app.RecorddataButton.ButtonPushedFcn = createCallbackFcn(app, @RecorddataButtonPushed, true);
            app.RecorddataButton.Text = 'Record data';
            app.JointsPositionPanel = uipanel(app.UIFigure);
            app.JointsPositionPanel.Title = 'Joints Position [º]';
            app.J2SliderLabel = uilabel(app.JointsPositionPanel);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.JointsPositionPanel);
            app.J2Slider.Limits = [-115.3 115.3];
            app.J2Slider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.J2Slider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J2Slider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.J2Slider.Value = 115.3;
            app.J3SliderLabel = uilabel(app.JointsPositionPanel);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.JointsPositionPanel);
            app.J3Slider.Limits = [-115.3 115.3];
            app.J3Slider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.J3Slider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.J3Slider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.J3Slider.Value = 115.3;
            app.J1SliderLabel = uilabel(app.JointsPositionPanel);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.JointsPositionPanel);
            app.J1Slider.Limits = [-90 90];
            app.J1Slider.MajorTicks = [-90 -45 0 45 90];
            app.J1Slider.MajorTickLabels = {'-90', '-45', '0', '45', '90'};
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @J1SliderValueChanged, true);
            app.J1Slider.MinorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.J1Slider.Value = 40;
            app.JointsPWMPanel = uipanel(app.UIFigure);
            app.JointsPWMPanel.Title = 'Joints PWM [%]';
            app.J2PWMSliderLabel = uilabel(app.JointsPWMPanel);
            app.J2PWMSliderLabel.Text = 'J2 PWM';
            app.J2PWMSlider = uislider(app.JointsPWMPanel);
            app.J2PWMSlider.MajorTicks = [0 25 50 75 100];
            app.J2PWMSlider.ValueChangedFcn = createCallbackFcn(app, @J2PWMSliderValueChanged, true);
            app.J3PWMSliderLabel = uilabel(app.JointsPWMPanel);
            app.J3PWMSliderLabel.Text = 'J3 PWM';
            app.J3PWMSlider = uislider(app.JointsPWMPanel);
            app.J3PWMSlider.MajorTicks = [0 25 50 75 100];
            app.J3PWMSlider.ValueChangedFcn = createCallbackFcn(app, @J3PWMSliderValueChanged, true);
            app.LinksPressurebarPanel = uipanel(app.UIFigure);
            app.LinksPressurebarPanel.Title = 'Links Pressure [bar]';
            app.VSL1SliderLabel = uilabel(app.LinksPressurebarPanel);
            app.VSL1SliderLabel.Text = 'VSL 1';
            app.VSL1Slider = uislider(app.LinksPressurebarPanel);
            app.VSL1Slider.Limits = [0 3];
            app.VSL1Slider.MajorTicks = [0 1 2 3];
            app.VSL1Slider.ValueChangedFcn = createCallbackFcn(app, @VSL1SliderValueChanged, true);
            app.VSL1Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.VSL2SliderLabel = uilabel(app.LinksPressurebarPanel);
            app.VSL2SliderLabel.Text = 'VSL 2';
            app.VSL2Slider = uislider(app.LinksPressurebarPanel);
            app.VSL2Slider.Limits = [0 3];
            app.VSL2Slider.MajorTicks = [0 1 2 3];
            app.VSL2Slider.ValueChangedFcn = createCallbackFcn(app, @VSL2SliderValueChanged, true);
            app.J1VelocitySliderLabel = uilabel(app.UIFigure);
            app.J1VelocitySliderLabel.Text = 'J1 Velocity';
            app.J1VelocitySlider = uislider(app.UIFigure);
            app.J1VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @J1VelocitySliderValueChanged, true);
            app.CartesianPositionmmPanel = uipanel(app.UIFigure);
            app.CartesianPositionmmPanel.Title = 'Cartesian Position [mm]';
            app.YSliderLabel = uilabel(app.CartesianPositionmmPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartesianPositionmmPanel);
            app.YSlider.Limits = [-115.3 115.3];
            app.YSlider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.YSlider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.YSlider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.YSlider.Value = 115.3;
            app.ZSliderLabel = uilabel(app.CartesianPositionmmPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartesianPositionmmPanel);
            app.ZSlider.Limits = [-115.3 115.3];
            app.ZSlider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.ZSlider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.ZSlider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.ZSlider.Value = 115.3;
            app.XSliderLabel = uilabel(app.CartesianPositionmmPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartesianPositionmmPanel);
            app.XSlider.Limits = [0 180];
            app.XSlider.MajorTicks = [0 45 90 135 180];
            app.XSlider.MajorTickLabels = {'0', '45', '90', '135', '180'};
            app.XSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.XSlider.Value = 115.3;
            app.Valve1Button = uibutton(app.UIFigure, 'state');
            app.Valve1Button.ValueChangedFcn = createCallbackFcn(app, @Valve1ButtonValueChanged, true);
            app.Valve1Button.Text = 'Valve1';
            app.Valve2Button = uibutton(app.UIFigure, 'state');
            app.Valve2Button.ValueChangedFcn = createCallbackFcn(app, @Valve2ButtonValueChanged, true);
            app.Valve2Button.Text = 'Valve2';
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.CartesianpositionEditFieldLabel = uilabel(app.UIFigure);
            app.CartesianpositionEditFieldLabel.Text = 'Cartesian position';
            app.CartesianpositionEditField = uieditfield(app.UIFigure, 'text');
            app.CartesianpositionEditField.Value = '[x,y,z]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotControl_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end