classdef robotControl_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RobotControlAppLabel          matlab.ui.control.Label
        BasicsPanel                   matlab.ui.container.Panel
        InitializationButton          matlab.ui.control.Button
        TorqueEnableButton            matlab.ui.control.StateButton
        robotHomeButton               matlab.ui.control.Button
        SearchPortsButton             matlab.ui.control.Button
        DXLPortLabel                  matlab.ui.control.Label
        DXLPortDropDown               matlab.ui.control.DropDown
        ArduinoVSLDropDownLabel       matlab.ui.control.Label
        ArduinoVSLDropDown            matlab.ui.control.DropDown
        StatusLabel                   matlab.ui.control.Label
        StatusLabel_2                 matlab.ui.control.Label
        ArduinoStepperDropDownLabel   matlab.ui.control.Label
        ArduinoStepperDropDown        matlab.ui.control.DropDown
        RecorddataButton              matlab.ui.control.Button
        JointsPositionPanel           matlab.ui.container.Panel
        J2SliderLabel                 matlab.ui.control.Label
        J2Slider                      matlab.ui.control.Slider
        J3SliderLabel                 matlab.ui.control.Label
        J3Slider                      matlab.ui.control.Slider
        J1SliderLabel                 matlab.ui.control.Label
        J1Slider                      matlab.ui.control.Slider
        JointsPWMPanel                matlab.ui.container.Panel
        J2PWMSliderLabel              matlab.ui.control.Label
        J2PWMSlider                   matlab.ui.control.Slider
        J3PWMSliderLabel              matlab.ui.control.Label
        J3PWMSlider                   matlab.ui.control.Slider
        LinksPressurebarPanel         matlab.ui.container.Panel
        VSL1SliderLabel               matlab.ui.control.Label
        VSL1Slider                    matlab.ui.control.Slider
        VSL2SliderLabel               matlab.ui.control.Label
        VSL2Slider                    matlab.ui.control.Slider
        J1VelocitySliderLabel         matlab.ui.control.Label
        J1VelocitySlider              matlab.ui.control.Slider
        CartesianPositionmmPanel      matlab.ui.container.Panel
        YSliderLabel                  matlab.ui.control.Label
        YSlider                       matlab.ui.control.Slider
        ZSliderLabel                  matlab.ui.control.Label
        ZSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        Valve1Button                  matlab.ui.control.StateButton
        Valve2Button                  matlab.ui.control.StateButton
        MovejointButton               matlab.ui.control.Button
        CartesianpositionEditFieldLabel  matlab.ui.control.Label
        CartesianpositionEditField    matlab.ui.control.EditField
        MovelinearButton              matlab.ui.control.Button
        CartesianpositionEditField_2Label  matlab.ui.control.Label
        CartesianpositionEditField_2  matlab.ui.control.EditField
        MovejointButton_2             matlab.ui.control.Button
        MovelinearButton_2            matlab.ui.control.Button
        GetPositionButton             matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %       PUBLIC PROPERTIES       %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    properties (Access = public)
        %% AUXILIAR %%
        monitor_figure;             % Figure name of the robotMonitor app
        bMonitoring = 0;            % Boolean variable - Monitoring ON/OFF       
    end
    
    
    properties (Constant)
        
    end
    
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                               %
    %       PUBLIC FUNCTIONS        %
    %                               %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = public)
        
    end
    
    methods (Access = private)          
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global bIni
            clear global; 
            app.StatusLabel_2.Text = 'System not connected';
            bIni = 0;
        end

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
            global robotControl_app DXLPort StepperPort PressurePort J1_velocity DXL1_ID DXL2_ID...
                bIni
            
            app.StatusLabel_2.Text = 'Initializing...';
            
            robotControl_app = app;
            DXLPort = app.DXLPortDropDown.Value;
            StepperPort = app.ArduinoStepperDropDown.Value;
            PressurePort = app.ArduinoVSLDropDown.Value;
            bIni = 0;
             
            if ~bIni
                Initialization();
                            
                app.TorqueEnableButton.Value = 1;
        
                % Set J1 velocity to 25%
                J1_velocity = 25;
                app.J1VelocitySlider.Value = J1_velocity;
            
                % Enable Dynamixel Torque
                enableTorque(DXL1_ID);
                enableTorque(DXL2_ID);
                
                % Initial position
                app.J1Slider.Value = 0;
                app.J2Slider.Value = 90;
                app.J3Slider.Value = 0;
                        
                % Set PWM to 10%
                initial_PWM = 0.15*DXL_MAXIMUM_PWM_VALUE;
                app.J2PWMSlider.Value = 15;
                app.J3PWMSlider.Value = 15;
                setPWM(DXL1_ID,initial_PWM);
                setPWM(DXL2_ID,initial_PWM);
                
                % Move to Home Position
                MoveJ([app.J1Slider.Value,app.J2Slider.Value,app.J3Slider.Value]);
                
                app.StatusLabel_2.Text = 'System ready';
            end
        end

        % Button pushed function: SearchPortsButton
        function SearchPortsButtonPushed(app, event)
            % Get the list of connected devices
            app.DXLPortDropDown.Items = seriallist;
            app.ArduinoVSLDropDown.Items = seriallist;
            app.ArduinoStepperDropDown.Items = seriallist;
        end

        % Value changed function: TorqueEnableButton
        function TorqueEnableButtonValueChanged(app, event)
            global DXL1_ID DXL2_ID
            if app.TorqueEnableButton.Value
                enableTorque(DXL1_ID);
                enableTorque(DXL2_ID);
            else
                disableTorque(DXL1_ID);
                disableTorque(DXL2_ID);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global bIni DXL1_ID DXL2_ID dxl_port_num arduinoSerial_stepper arduinoSerial_pressure lib_name
            if bIni
                % Disable Torque
%                 disableTorque(DXL1_ID);
%                 disableTorque(DXL2_ID);
%                 app.TorqueEnableButton.Value = -1;
                
                % Close port
                closePort(dxl_port_num);
                fclose(arduinoSerial_stepper);
                fclose(arduinoSerial_pressure);
                
                % Unload Library
                unloadlibrary(lib_name);
                
                % Close Monitoring app
%                 if app.bMonitoring
%                     close(app.monitor_figure);
%                 end
            end
            delete(app)
        end

        % Button pushed function: robotHomeButton
        function robotHomeButtonPushed(app, event)
            global DXL1_ID DXL2_ID
            if ~app.TorqueEnableButton.Value
                % Enable Torque
                enableTorque(DXL1_ID);
                enableTorque(DXL2_ID);
            end
            % Initial position
            app.J1Slider.Value = 0;
            app.J2Slider.Value = 90;
            app.J3Slider.Value = 0;

            MoveJ([app.J1Slider.Value,app.J2Slider.Value,app.J3Slider.Value]);
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            global bIni DXL1_ID
            if bIni
                if ~app.TorqueEnableButton.Value
                    % Enable Torque
                    enableTorque(DXL1_ID);
                end
                
                % Set Joints 2 and 3 position
                dxl1_goal_position = app.J2Slider.Value*4096/360 + 1024;
                dxl2_goal_position = app.J3Slider.Value*4096/360 + 2048;
                
                % Move Joints simultaneously
                moveDxl([dxl1_goal_position,dxl2_goal_position]);
            end
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            global bIni DXL2_ID
            if bIni
                if ~app.TorqueEnableButton.Value
                    % Enable Torque
                    enableTorque(DXL2_ID);
                end
                % Set Joints 2 and 3 position
                dxl1_goal_position = app.J2Slider.Value*4096/360 + 1024;
                dxl2_goal_position = app.J3Slider.Value*4096/360 + 2048;
                
                % Move Joints simultaneously
                moveDxl([dxl1_goal_position,dxl2_goal_position]); 
            end
        end

        % Value changed function: J2PWMSlider
        function J2PWMSliderValueChanged(app, event)
            global DXL1_ID
            % calculate PWM (PWM goes from 0 to 885, not 0 to 100%)
            PWM = app.J2PWMSlider.Value*885/100;
            
            setPWM(DXL1_ID,PWM);
        end

        % Value changed function: J3PWMSlider
        function J3PWMSliderValueChanged(app, event)
            global DXL2_ID
            % calculate PWM (PWM goes from 0 to 885, not 0 to 100%)
            PWM = app.J3PWMSlider.Value*885/100;
            
            setPWM(DXL2_ID,PWM);
        end

        % Value changed function: J1Slider
        function J1SliderValueChanged(app, event)
            moveStepper(app.J1Slider.Value);
        end

        % Button pushed function: RecorddataButton
        function RecorddataButtonPushed(app, event)
            global bIni
            % Open the robotMonitor app
            if bIni
                app.monitor_figure = robotMonitor;
                app.bMonitoring = 1;
            else
                app.StatusLabel_2.Text = 'Initialize the robot first';
            end
        end

        % Value changed function: J1VelocitySlider
        function J1VelocitySliderValueChanged(app, event)
            global J1_velocity
            J1_velocity = app.J1VelocitySlider.Value;   
        end

        % Value changed function: VSL1Slider
        function VSL1SliderValueChanged(app, event)
            % Ojo, esto hay que arreglarlo
            global arduinoSerial_pressure
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(app.arduinoSerial_pressure);
            end
        end

        % Value changed function: VSL2Slider
        function VSL2SliderValueChanged(app, event)
            % Ojo, esto hay que arreglarlo
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(app.arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(app.arduinoSerial_pressure);
            end
        end

        % Value changed function: Valve1Button
        function Valve1ButtonValueChanged(app, event)
            % Ojo, esto hay que arreglarlo
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(arduinoSerial_pressure);
            end
        end

        % Value changed function: Valve2Button
        function Valve2ButtonValueChanged(app, event)
            % Ojo, esto hay que arreglarlo
            % Send the PWM. 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle) 
            pressure_changed = 0;
            while pressure_changed(1) ~= '1'
                fprintf(arduinoSerial_pressure,mat2str([n_steps, app.VSL1Slider.Value/3, app.VSL2Slider.Value/3,app.Valve1Button,app.Valve2Button]));
                pressure_changed = fscanf(arduinoSerial_pressure);
            end
        end

        % Button pushed function: MovejointButton
        function MovejointButtonPushed(app, event)
            global DXL1_ID DXL2_ID DIST_THRESHOLD
            
            if ~app.TorqueEnableButton.Value
                % Enable Torque
                enableTorque(DXL1_ID);
                enableTorque(DXL2_ID);
            end
            
            % Get the goal position
            X = str2num(app.CartesianpositionEditField.Value);
            if length(X)~=3
                app.StatusLabel_2.Text = 'Invalid Poisition';
            else
                % Go to X position
                [Q,reachable] = goto(X);
                if reachable
                    app.J1Slider.Value = Q(1);
                    app.J2Slider.Value = Q(2);
                    app.J3Slider.Value = Q(3);
                    app.StatusLabel_2.Text = 'Moving...';
                    
                    %Wait until the movement is completed
%                     Q_current=getPosition();
%                     d_error = sqrt((Q(1)-Q_current(1))^2+(Q(2)-Q_current(2))^2+(Q(3)-Q_current(3))^2);
%                     while d_error>DIST_THRESHOLD
%                         Q_current=getPosition();
%                         d_error = sqrt((Q(1)-Q_current(1))^2+(Q(2)-Q_current(2))^2+(Q(3)-Q_current(3))^2);
%                         pause(0.01);
%                     end
                    app.StatusLabel_2.Text = 'Completed movement';
                else
                    app.StatusLabel_2.Text = 'Point not reachable';
                end                
            end
        end

        % Button pushed function: MovelinearButton
        function MovelinearButtonPushed(app, event)
            global DXL1_ID DXL2_ID
            
            if ~app.TorqueEnableButton.Value
                % Enable Torque
                enableTorque(DXL1_ID);
                enableTorque(DXL2_ID);
            end
            
            % Get the goal position
            X = str2num(app.CartesianpositionEditField.Value);
            if length(X)~=3
                app.StatusLabel_2.Text = 'Invalid Poisition';
            else
                LinearTrajectory_v2(X);
%                 if reachable
%                     app.StatusLabel_2.Text = 'Completed movement';
%                 else
%                     app.StatusLabel_2.Text = 'Unfeasible path';
%                 end                
            end
            Q = getPosition();
            app.J1Slider.Value = Q(1);
            app.J2Slider.Value = Q(2);
            app.J3Slider.Value = Q(3);
        end

        % Button pushed function: MovejointButton_2
        function MovejointButton_2Pushed(app, event)
            global DXL1_ID DXL2_ID DIST_THRESHOLD
            
            if ~app.TorqueEnableButton.Value
                % Enable Torque
                enableTorque(DXL1_ID);
                enableTorque(DXL2_ID);
            end
            
            % Get the goal position
            Q = str2num(app.CartesianpositionEditField_2.Value);
            if length(Q)~=3
                app.StatusLabel_2.Text = 'Invalid Poisition';
            else
                % Go to X position
                MoveJ(Q);
                if reachable
                    app.J1Slider.Value = Q(1);
                    app.J2Slider.Value = Q(2);
                    app.J3Slider.Value = Q(3);
                    app.StatusLabel_2.Text = 'Moving...';
                    
                    %Wait until the movement is completed
%                     Q_current=getPosition();
%                     d_error = sqrt((Q(1)-Q_current(1))^2+(Q(2)-Q_current(2))^2+(Q(3)-Q_current(3))^2);
%                     while d_error>DIST_THRESHOLD
%                         Q_current=getPosition();
%                         d_error = sqrt((Q(1)-Q_current(1))^2+(Q(2)-Q_current(2))^2+(Q(3)-Q_current(3))^2);
%                         pause(0.01);
%                     end
                    app.StatusLabel_2.Text = 'Completed movement';
                else
                    app.StatusLabel_2.Text = 'Point not reachable';
                end                
            end
        end

        % Button pushed function: GetPositionButton
        function GetPositionButtonPushed(app, event)
            Q = getPosition();
            app.EditField.Value = num2str(Q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RobotControlAppLabel = uilabel(app.UIFigure);
            app.RobotControlAppLabel.Text = 'Robot Control App';
            app.BasicsPanel = uipanel(app.UIFigure);
            app.BasicsPanel.Title = 'Basics';
            app.InitializationButton = uibutton(app.BasicsPanel, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Text = 'Initialization';
            app.TorqueEnableButton = uibutton(app.BasicsPanel, 'state');
            app.TorqueEnableButton.ValueChangedFcn = createCallbackFcn(app, @TorqueEnableButtonValueChanged, true);
            app.TorqueEnableButton.Text = 'Torque Enable';
            app.robotHomeButton = uibutton(app.BasicsPanel, 'push');
            app.robotHomeButton.ButtonPushedFcn = createCallbackFcn(app, @robotHomeButtonPushed, true);
            app.robotHomeButton.Text = 'robotHome';
            app.SearchPortsButton = uibutton(app.BasicsPanel, 'push');
            app.SearchPortsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchPortsButtonPushed, true);
            app.SearchPortsButton.Text = 'Search Ports';
            app.DXLPortLabel = uilabel(app.BasicsPanel);
            app.DXLPortLabel.Text = 'DXL Port';
            app.DXLPortDropDown = uidropdown(app.BasicsPanel);
            app.DXLPortDropDown.Items = {};
            app.DXLPortDropDown.Value = {};
            app.ArduinoVSLDropDownLabel = uilabel(app.BasicsPanel);
            app.ArduinoVSLDropDownLabel.Text = 'Arduino VSL';
            app.ArduinoVSLDropDown = uidropdown(app.BasicsPanel);
            app.ArduinoVSLDropDown.Items = {};
            app.ArduinoVSLDropDown.Value = {};
            app.StatusLabel = uilabel(app.BasicsPanel);
            app.StatusLabel.Text = 'Status:';
            app.StatusLabel_2 = uilabel(app.BasicsPanel);
            app.StatusLabel_2.Text = 'Status';
            app.ArduinoStepperDropDownLabel = uilabel(app.BasicsPanel);
            app.ArduinoStepperDropDownLabel.Text = 'Arduino Stepper';
            app.ArduinoStepperDropDown = uidropdown(app.BasicsPanel);
            app.ArduinoStepperDropDown.Items = {};
            app.ArduinoStepperDropDown.Value = {};
            app.RecorddataButton = uibutton(app.BasicsPanel, 'push');
            app.RecorddataButton.ButtonPushedFcn = createCallbackFcn(app, @RecorddataButtonPushed, true);
            app.RecorddataButton.Text = 'Record data';
            app.JointsPositionPanel = uipanel(app.UIFigure);
            app.JointsPositionPanel.Title = 'Joints Position [º]';
            app.J2SliderLabel = uilabel(app.JointsPositionPanel);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.JointsPositionPanel);
            app.J2Slider.Limits = [-25.3 205.3];
            app.J2Slider.MajorTicks = [-25.3 32.35 90 147.65 205.3];
            app.J2Slider.MajorTickLabels = {'-25.3', '32.35', '90', '147.65', '205.3'};
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J2Slider.MinorTicks = [-25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7 124.7 134.7 144.7 154.7 164.7 174.7 184.7 194.7 204.7];
            app.J2Slider.Value = 115.3;
            app.J3SliderLabel = uilabel(app.JointsPositionPanel);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.JointsPositionPanel);
            app.J3Slider.Limits = [-115.3 115.3];
            app.J3Slider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.J3Slider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.J3Slider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.J3Slider.Value = 115.3;
            app.J1SliderLabel = uilabel(app.JointsPositionPanel);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.JointsPositionPanel);
            app.J1Slider.Limits = [-90 90];
            app.J1Slider.MajorTicks = [-90 -45 0 45 90];
            app.J1Slider.MajorTickLabels = {'-90', '-45', '0', '45', '90'};
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @J1SliderValueChanged, true);
            app.J1Slider.MinorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.J1Slider.Value = 40;
            app.JointsPWMPanel = uipanel(app.UIFigure);
            app.JointsPWMPanel.Title = 'Joints PWM [%]';
            app.J2PWMSliderLabel = uilabel(app.JointsPWMPanel);
            app.J2PWMSliderLabel.Text = 'J2 PWM';
            app.J2PWMSlider = uislider(app.JointsPWMPanel);
            app.J2PWMSlider.MajorTicks = [0 25 50 75 100];
            app.J2PWMSlider.ValueChangedFcn = createCallbackFcn(app, @J2PWMSliderValueChanged, true);
            app.J3PWMSliderLabel = uilabel(app.JointsPWMPanel);
            app.J3PWMSliderLabel.Text = 'J3 PWM';
            app.J3PWMSlider = uislider(app.JointsPWMPanel);
            app.J3PWMSlider.MajorTicks = [0 25 50 75 100];
            app.J3PWMSlider.ValueChangedFcn = createCallbackFcn(app, @J3PWMSliderValueChanged, true);
            app.LinksPressurebarPanel = uipanel(app.UIFigure);
            app.LinksPressurebarPanel.Title = 'Links Pressure [bar]';
            app.VSL1SliderLabel = uilabel(app.LinksPressurebarPanel);
            app.VSL1SliderLabel.Text = 'VSL 1';
            app.VSL1Slider = uislider(app.LinksPressurebarPanel);
            app.VSL1Slider.Limits = [0 3];
            app.VSL1Slider.MajorTicks = [0 1 2 3];
            app.VSL1Slider.ValueChangedFcn = createCallbackFcn(app, @VSL1SliderValueChanged, true);
            app.VSL1Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.VSL2SliderLabel = uilabel(app.LinksPressurebarPanel);
            app.VSL2SliderLabel.Text = 'VSL 2';
            app.VSL2Slider = uislider(app.LinksPressurebarPanel);
            app.VSL2Slider.Limits = [0 3];
            app.VSL2Slider.MajorTicks = [0 1 2 3];
            app.VSL2Slider.ValueChangedFcn = createCallbackFcn(app, @VSL2SliderValueChanged, true);
            app.J1VelocitySliderLabel = uilabel(app.UIFigure);
            app.J1VelocitySliderLabel.Text = 'J1 Velocity';
            app.J1VelocitySlider = uislider(app.UIFigure);
            app.J1VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @J1VelocitySliderValueChanged, true);
            app.CartesianPositionmmPanel = uipanel(app.UIFigure);
            app.CartesianPositionmmPanel.Title = 'Cartesian Position [mm]';
            app.YSliderLabel = uilabel(app.CartesianPositionmmPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CartesianPositionmmPanel);
            app.YSlider.Limits = [-115.3 115.3];
            app.YSlider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.YSlider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.YSlider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.YSlider.Value = 115.3;
            app.ZSliderLabel = uilabel(app.CartesianPositionmmPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.CartesianPositionmmPanel);
            app.ZSlider.Limits = [-115.3 115.3];
            app.ZSlider.MajorTicks = [-115.3 -57.65 0 57.65 115.3];
            app.ZSlider.MajorTickLabels = {'-115.3', '-57.65', '0', '57.65', '115.3'};
            app.ZSlider.MinorTicks = [-115.3 -105.3 -95.3 -85.3 -75.3 -65.3 -55.3 -45.3 -35.3 -25.3 -15.3 -5.3 4.7 14.7 24.7 34.7 44.7 54.7 64.7 74.7 84.7 94.7 104.7 114.7];
            app.ZSlider.Value = 115.3;
            app.XSliderLabel = uilabel(app.CartesianPositionmmPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.CartesianPositionmmPanel);
            app.XSlider.Limits = [0 180];
            app.XSlider.MajorTicks = [0 45 90 135 180];
            app.XSlider.MajorTickLabels = {'0', '45', '90', '135', '180'};
            app.XSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.XSlider.Value = 115.3;
            app.Valve1Button = uibutton(app.UIFigure, 'state');
            app.Valve1Button.ValueChangedFcn = createCallbackFcn(app, @Valve1ButtonValueChanged, true);
            app.Valve1Button.Text = 'Valve1';
            app.Valve2Button = uibutton(app.UIFigure, 'state');
            app.Valve2Button.ValueChangedFcn = createCallbackFcn(app, @Valve2ButtonValueChanged, true);
            app.Valve2Button.Text = 'Valve2';
            app.MovejointButton = uibutton(app.UIFigure, 'push');
            app.MovejointButton.ButtonPushedFcn = createCallbackFcn(app, @MovejointButtonPushed, true);
            app.MovejointButton.Text = 'Move (joint)';
            app.CartesianpositionEditFieldLabel = uilabel(app.UIFigure);
            app.CartesianpositionEditFieldLabel.Text = 'Cartesian position';
            app.CartesianpositionEditField = uieditfield(app.UIFigure, 'text');
            app.CartesianpositionEditField.Value = '[x,y,z]';
            app.MovelinearButton = uibutton(app.UIFigure, 'push');
            app.MovelinearButton.ButtonPushedFcn = createCallbackFcn(app, @MovelinearButtonPushed, true);
            app.MovelinearButton.Text = 'Move (linear)';
            app.CartesianpositionEditField_2Label = uilabel(app.UIFigure);
            app.CartesianpositionEditField_2Label.Text = 'Cartesian position';
            app.CartesianpositionEditField_2 = uieditfield(app.UIFigure, 'text');
            app.CartesianpositionEditField_2.Value = '[q1,q2,q3]';
            app.MovejointButton_2 = uibutton(app.UIFigure, 'push');
            app.MovejointButton_2.ButtonPushedFcn = createCallbackFcn(app, @MovejointButton_2Pushed, true);
            app.MovejointButton_2.Text = 'Move (joint)';
            app.MovelinearButton_2 = uibutton(app.UIFigure, 'push');
            app.MovelinearButton_2.Text = 'Move (linear)';
            app.GetPositionButton = uibutton(app.UIFigure, 'push');
            app.GetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButtonPushed, true);
            app.GetPositionButton.Text = 'Get Position';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotControl_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end