classdef robotMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Axes                   matlab.ui.control.UIAxes
        RecordingButton        matlab.ui.control.StateButton
        VariableDropDownLabel  matlab.ui.control.Label
        VariableDropDown       matlab.ui.control.DropDown
        SavedataButton         matlab.ui.control.Button
        SendtoworkspaceButton  matlab.ui.control.Button
        PlotButton             matlab.ui.control.StateButton
        messageLabel           matlab.ui.control.Label
        StatusLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % Joint 2
        J2_present_angle;
        J2_array_angle;
        J2_present_velocity;
        J2_array_velocity;
        J2_present_PWM;
        J2_array_PWM;
        
        % Joint 3
        J3_present_angle;
        J3_array_angle;
        J3_present_velocity;
        J3_array_velocity;
        J3_present_PWM;
        J3_array_PWM;
        
        % Pressure
        VSL1_present_pressure;
        VSL1_array_pressure;
        VSL2_present_pressure; 
        VSL2_array_pressure;  
        
        % Instance to the robotControl_app class
        robotControl_app;
    end
    
    methods (Access = public)
        
        function getAngle(app)
            % Get J2 angle
            app.J2_present_angle = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL1_ID, app.robotControl_app.ADD_PRESENT_POSITION);
            app.J2_array_angle = [app.J2_array_angle app.J2_present_angle];
            
            % Get J3 angle
            app.J3_present_angle = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL2_ID, app.robotControl_app.ADD_PRESENT_POSITION);
            app.J3_array_angle = [app.J3_array_angle app.J3_present_angle];
        end
        
        function getVelocity(app)
            % Get J2 velocity
            app.J2_present_velocity = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL1_ID, app.robotControl_app.ADD_PRESENT_VELOCITY);
            app.J2_array_velocity = [app.J2_array_velocity app.J2_present_velocity];
            
            % Get J3 velocity
            app.J3_present_velocity = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL2_ID, app.robotControl_app.ADD_PRESENT_VELOCITY);
            app.J3_array_velocity = [app.J3_array_velocity app.J3_present_velocity];     
        end
        
        function getPWM(app)
            app.J2_present_PWM = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL1_ID, app.robotControl_app.ADD_PRESENT_PWM);
            if app.J2_present_PWM < 1000
                app.J2_array_PWM = [app.J2_array_PWM app.J2_present_PWM];
            end
            
            app.J3_present_PWM = read2ByteTxRx(app.robotControl_app.dxl_port_num, app.robotControl_app.PROTOCOL_VERSION, app.robotControl_app.DXL2_ID, app.robotControl_app.ADD_PRESENT_PWM);
            if app.J3_present_PWM < 1000
                app.J3_array_PWM = [app.J3_array_PWM app.J3_present_PWM];
            end
        end
        
        function getPressure(app)
            % Get VSL1 pressure
            app.VSL1_present_pressure = readVoltage(app.robotControl_app.arduinoObject, app.robotControl_app.VSL1FeedbackPin);
            app.VSL1_array_pressure = [app.VSL1_array_pressure app.VSL1_present_pressure];
            
            % Get VSL2 pressure
            app.VSL1_present_pressure = readVoltage(app.robotControl_app.arduinoObject, app.robotControl_app.VSL2FeedbackPin);
            app.VSL2_array_pressure = [app.VSL2_array_pressure app.VSL2_present_pressure];    
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robotControl_app = evalin('base','robotControl_app');
            app.messageLabel.Text = 'Initializing';
            
            % initialization
            app.J2_array_angle = [];
            app.J2_array_velocity = [];
            app.J2_array_PWM = [];
            
            app.J3_array_angle = [];
            app.J3_array_velocity = [];
            app.J3_array_PWM = [];
            
            app.VSL1_array_pressure = [];
            app.VSL2_array_pressure = [];
            
            app.messageLabel.Text = 'System ready';
        end

        % Value changed function: RecordingButton
        function RecordingButtonValueChanged(app, event)
            cont = 0;
            
            % Status Message
            if app.RecordingButton.Value 
                app.messageLabel.Text = 'Recording data';
            else
                app.messageLabel.Text = 'Not recording';
            end
            
            % Recording while the button is pushed
            while app.RecordingButton.Value 
               
               % Get data
               getAnlge(app);
               getVelocity(app);
               getPWM(app);
               getPressure(app);

               % If the plot button is pushed
               if app.PlotButton.Value
                    if cont == 10 
                        switch app.VariableDropDown.Value
                            case 'Joint position'
                                plot(app.Axes, 1:length(app.J2_array_angle),app.J2_array_angle,...
                                    1:length(app.J3_array_angle),app.J3_array_angle);
                            case 'Joint velocity'
                                plot(app.Axes, app.J2_array_angle);
                            case 'Joint PWM'
                                plot(app.Axes, app.J2_array_angle);
                            case 'Cartesian position'
                                plot(app.Axes, app.J2_array_angle);
                            case 'Cartesian velocity'
                                plot(app.Axes, app.J2_array_angle);
                            case 'Pressure'
                                plot(app.Axes, app.J2_array_angle);
                            case 'J2 angle'
                                plot(app.Axes, app.J2_array_angle);
                            case 'J3 angle'
                                plot(app.Axes, app.J3_array_angle);
                            case 'J2 velocity'
                                plot(app.Axes, app.J2_array_velocity);
                            case 'J3 velocity'
                                plot(app.Axes, app.J3_array_velocity);
                            case 'J2 PWM'
                                plot(app.Axes, app.J2_array_PWM);
                            case 'J3 PWM'
                                plot(app.Axes, app.J3_array_PWM);
                            case 'VSL1 pressure'
                                plot(app.Axes, app.VSL1_array_pressure);
                            case 'VSL2 pressure'
                                plot(app.Axes, app.VSL2_array_pressure);      
                        end
                        cont = 0;
                    end
                    cont = cont + 1;
                end
                                            
                pause(0.01);
            end
        end

        % Button pushed function: SendtoworkspaceButton
        function SendtoworkspaceButtonPushed(app, event)
            if ~app.RecordingButton.Value
                app.messageLabel.Text = 'Sending data to workspace';
                assignin('base','jointAngle.J2_angle',app.J2_array_angle);
                assignin('base','jointAngle.J3_angle',app.J2_array_angle);
                assignin('base','jointVelocity.J2_velocity',app.J2_array_velocity);
                assignin('base','jointVelocity.J3_velocity',app.J3_array_velocity);
                assignin('base','jointPWM.J2_PWM',app.J2_array_PWM);
                assignin('base','jointPWM.J3_PWM',app.J3_array_PWM);
                assignin('base','pressure.VSL1_Pressure',app.VSL1_array_pressure);
                assignin('base','pressure.VSL2_Pressure',app.VSL2_array_pressure); 
                app.messageLabel.Text = 'Data sent';
            else
                app.messageLabel.Text = 'Cannot send data while recording';
            end
            
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            if ~app.RecordingButton.Value
                app.messageLabel.Text = 'Saving data';
                SendtoworkspaceButtonPushed(app, event);
                jointAngle = evalin('base','jointAngle');
                jointVelocity = evalin('base','jointVelocity');
                jointPWM = evalin('base','jointPWM');
                pressure = evalin('base','pressure');
                save('Experiments/experiment',jointAngle,jointVelocity,jointPWM,pressure); 
                app.messageLabel.Text = 'Data saved';
            else
                app.messageLabel.Text = 'Cannot save data while recording';
            end           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Variable')
            xlabel(app.Axes, 'X axis')
            ylabel(app.Axes, 'Y axis')
            app.RecordingButton = uibutton(app.UIFigure, 'state');
            app.RecordingButton.ValueChangedFcn = createCallbackFcn(app, @RecordingButtonValueChanged, true);
            app.RecordingButton.Text = 'Recording';
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'Joint position', 'Joint velocity', 'Joint PWM', 'Cartesian Position', 'Cartesian velocity', 'Pressure', 'J2 angle', 'J2 velocity', 'J3 angle', 'J3 velocity', 'J2 PWM', 'J3 PWM', 'VSL1 Pressure', 'VSL2 Pressure'};
            app.VariableDropDown.Value = 'J2 PWM';
            app.SavedataButton = uibutton(app.UIFigure, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.SendtoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.SendtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoworkspaceButtonPushed, true);
            app.SendtoworkspaceButton.Text = 'Send to workspace';
            app.PlotButton = uibutton(app.UIFigure, 'state');
            app.PlotButton.Text = 'Plot';
            app.messageLabel = uilabel(app.UIFigure);
            app.messageLabel.Text = 'message';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end