classdef robotMonitor_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VSLmanipulatorControlAppLabel  matlab.ui.control.Label
        JointPositionsPanel            matlab.ui.container.Panel
        J1SliderLabel                  matlab.ui.control.Label
        J1Slider                       matlab.ui.control.Slider
        J2SliderLabel                  matlab.ui.control.Label
        J2Slider                       matlab.ui.control.Slider
        J3SliderLabel                  matlab.ui.control.Label
        J3Slider                       matlab.ui.control.Slider
        VelocitySliderLabel            matlab.ui.control.Label
        VelocitySlider                 matlab.ui.control.Slider
        ConnectButton                  matlab.ui.control.Button
        SerialPortDropDownLabel        matlab.ui.control.Label
        SerialPortDropDown             matlab.ui.control.DropDown
        SearchSerialPortButton         matlab.ui.control.Button
        ArduinonotconnectedLabel       matlab.ui.control.Label
        RobotHomeButton                matlab.ui.control.Button
        PressureControlPanel           matlab.ui.container.Panel
        VSL1SliderLabel                matlab.ui.control.Label
        VSL1Slider                     matlab.ui.control.Slider
        VSL2Label                      matlab.ui.control.Label
        VSL2Slider                     matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
        RecordingButton                matlab.ui.control.StateButton
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                         %
    %       PROPERTIES        %
    %                         %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%
     
    properties (Access = private)
        % Motors 
        J1;
        J2;
        J3;
        min_pulseWidth = 0.58e-3;      %Minimum pulse width of the servos in seconds
        max_pulseWidth = 2.52e-3;      %Maximum pulse width of the servos in seconds
        
        % Define Port COM
        PortCOM;
        
        % ArduinoObject
        arduinoObject;
        
        % Motors PinOut
        J1DirPin = 'D2';
        J1StepPin = 'D3';
        J2Pin = 'D4';
        J3Pin = 'D5'; 
        
        % Pressure Regulators Pin
        VSL1_GoalPressurePin = 'D6';
        VSL2_GoalPressurePin = 'D7'; 
        VSL1_CurrentPressurePin = 'A0';
        VSL2_CurrentPressurePin = 'A1';
        
        % Goal pressure
        VSL1_GoalPressure = 0;
        VSL2_GoalPressure = 0;
        
        % Current pressure
        VSL1_CurrentPressure;
        VSL2_CurrentPressure;
        
        % Arrays of pressure
        VSL1_PressureArray;
        VSL2_PressureArray;
                
        % Velocity
        maxVelocityJ1 = 0.5;    % (0.5seconds delay when switching the coils)
        minVelocityJ1 = 3;      % (3 seconds delay when switching the coils)
        velocity = 50;          % 50%
        
        % Current joint position
        J1Position;
        J2Position;
        J3Position;
        
        nDegrees = 5;                                   % Number of degrees for each servo motor step
        tStep_Vmax = 0.021;                             % Time of each step at max velocity 
        tStep;                                          % Time of each step at actual velocity
        
        % Time
        t;
        
        % Stop
        stop;
        
    end
    
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                         %
    %       FUNCTIONS         %
    %                         %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Function to Get the robot to home position
        function robotHome(app)
            % Set the initial position of sliders
            app.J1Slider.Value = 180;
            app.J2Slider.Value = 90;
            app.J3Slider.Value = 90;
            
            % Set the initial position of the robot
            writePosition(app.J2,app.J2Slider.Value/180); % Servo object goes from 0 to 1
            app.J2Position = app.J2Slider.Value;
            
            writePosition(app.J3,app.J2Slider.Value/180); % Servo object goes from 0 to 1
            app.J3Position = app.J3Slider.Value;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Function to read internal pressure of the links
        function readPressure(app)
            cont = 1;
            tic;
            while 1
                if app.t>=5*cont
                   assignin('base', 'Pressure_VSL1', [evalin('base','Pressure_VSL1'), app.VSL1_PressureArray]);
                   app.t = toc;
                   app.VSL1_PressureArray = app.VSL1_CurrentPressure;
                   cont = cont+1;
                end
                app.t = [app.t, toc];
                app.VSL1_CurrentPressure = readVoltage(app.arduinoObject, app.VSL1_CurrentPressurePin);
                app.VSL1_PressureArray = [app.VSL1_PressureArray app.VSL1_CurrentPressure];
                
                app.VSL2_CurrentPressure = readVoltage(app.arduinoObject, app.VSL2_CurrentPressurePin);
                app.VSL2_PressureArray = [app.VSL1_PressureArray app.VSL2_CurrentPressure]; 
                
                plot(app.UIAxes, app.t, app.VSL1_PressureArray,'r'); %, app.t, app.VSL2_PressureArray,'b');
                
                pause(0.01);
                
                if app.stop
                    assignin('base', 'Pressure_VSL1', [evalin('base','Pressure_VSL1'), app.VSL1_PressureArray]);
                    %assignin('base', 'Pressure_VSL1', app.t);
                    break;
                end
            end
        end
   
    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %function to initialize variables
        function initialization(app)
            % Start counting time
            tic;
            
            % Create the arduino object
            app.arduinoObject = arduino(app.PortCOM);
            
            % Create the servo objects
            app.J2 = servo(app.arduinoObject, app.J2Pin); %, 'MinPulseDuration', app.min_pulseWidth, 'MaxPulseDuration', app.max_pulseWidth);
            app.J3 = servo(app.arduinoObject, app.J3Pin); %, 'MinPulseDuration', app.min_pulseWidth, 'MaxPulseDuration', app.max_pulseWidth); 
            
            % Initial (home) position
            robotHome(app);      
            
            % Set Initial Velocity to 50%   
            app.VelocitySlider.Value = app.velocity;
            
            % Set time step
            app.tStep = app.tStep_Vmax + app.tStep_Vmax/app.velocity*100; 
            
            % Create variables in workspace
            assignin('base', 'Pressure_VSL1', 0);
            assignin('base', 'time', 0);
            
            % Initializate start/stop recording
            app.stop = 0;
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Set the serial port COM
            app.PortCOM = app.SerialPortDropDown.Value;
                   
            % Show a message that the arduino is connected correctly
            app.ArduinonotconnectedLabel.Text = 'Arduino correctly connected';
            
            % Initialization
            initialization(app);
        end

        % Button pushed function: SearchSerialPortButton
        function SearchSerialPortButtonPushed(app, event)
            % Get the list of connected devices
            app.SerialPortDropDown.Items = seriallist;
        end

        % Button pushed function: RobotHomeButton
        function RobotHomeButtonPushed(app, event)
            % Call the robot home function
            robotHome(app);
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            % Change the value of J2  
            if app.J2Position<app.J2Slider.Value
                for pos = app.J2Position:app.nDegrees:app.J2Slider.Value
                    writePosition(app.J2,pos/180); % Servo object goes from 0 to 1
                    pause(app.tStep);
                end
            else
                cont = 0;
                for pos = app.J2Slider.Value:app.nDegrees:app.J2Position
                    writePosition(app.J2,(app.J2Position-cont)/180); % Servo object goes from 0 to 1
                    pause(app.tStep);
                    cont = cont + app.nDegrees;
                end
            end
            app.J2Position = app.J2Slider.Value;
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            app.velocity = app.VelocitySlider.Value;
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            % Change the value of J3  
            if app.J3Position<app.J3Slider.Value
                for pos = app.J3Position:app.nDegrees:app.J3Slider.Value
                    writePosition(app.J3,pos/180); % Servo object goes from 0 to 1
                    pause(app.tStep);
                end
            else
                cont = 0;
                for pos = app.J3Slider.Value:app.nDegrees:app.J3Position
                    writePosition(app.J3,(app.J3Position-cont)/180); % Servo object goes from 0 to 1
                    pause(app.tStep);
                    cont = cont + app.nDegrees;
                end
            end
            app.J3Position = app.J3Slider.Value;
        end

        % Value changed function: J1Slider
        function J1SliderValueChanged(app, event)
             % Change the value of J1  
            if app.J1Position<app.J1Slider.Value
                writeDigitalPin(app.arduinoObject, app.J1DirPin, 1);
            else
                writeDigitalPin(app.arduinoObject, app.J1DirPin, 0);
            end
            for pos = 0:abs(app.J1Slider.Value-app.J1Position)/1.8       %Each step is 1.8º
                writeDigitalPin(app.arduinoObject, app.J1StepPin, 1);
                pause(app.minVelocityJ1 - (app.minVelocityJ1- app.maxVelocityJ1)*(app.velocity/100));
                writeDigitalPin(app.arduinoObject, app.J1StepPin, 0);   
                pause(app.minVelocityJ1 - (app.minVelocityJ1- app.maxVelocityJ1)*(app.velocity/100));
            end
            app.J1Position = app.J1Slider.Value;
        end

        % Value changed function: VSL1Slider
        function VSL1SliderValueChanged(app, event)
            % Calculate the DutyCycle 
            app.VSL1_GoalPressure = app.VSL1Slider.Value/3;     % 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle)  
            % Write the PWM
            writePWMDutyCycle(app.arduinoObject,app.VSL1_GoalPressurePin, app.VSL1_GoalPressure);
            
        end

        % Value changed function: VSL2Slider
        function VSL2SliderValueChanged(app, event)
            % Calculate the DutyCycle 
            app.VSL2_GoalPressure = app.VSL1Slider.Value/3;     % 3 bar is the maximum pressure which corresponds with 1 (100% DutyCycle)  
            % Write the PWM
            writePWMDutyCycle(app.arduinoObject,app.VSL2_GoalPressurePin, app.VSL2_GoalPressure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: RecordingButton
        function RecordingButtonValueChanged(app, event)
            cont = 0;
            while app.RecordingButton.Value
                app.VSL1_CurrentPressure = readVoltage(app.arduinoObject, app.VSL1_CurrentPressurePin);
                assignin('base', 'Pressure_VSL1', [evalin('base','Pressure_VSL1'), app.VSL1_CurrentPressure]);
                
                app.t = toc;
                assignin('base', 'time', [evalin('base','time'), toc]);
                
                if cont == 3
                    plot(app.UIAxes, evalin('base','time'), evalin('base','Pressure_VSL1'),'r'); 
                    cont = 0;
                end
                pause(0.1);
                cont = cont + 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VSLmanipulatorControlAppLabel = uilabel(app.UIFigure);
            app.VSLmanipulatorControlAppLabel.Text = {'VSL manipulator '; 'Control App'};
            app.JointPositionsPanel = uipanel(app.UIFigure);
            app.JointPositionsPanel.Title = 'Joint Positions';
            app.J1SliderLabel = uilabel(app.JointPositionsPanel);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.JointPositionsPanel);
            app.J1Slider.Limits = [0 360];
            app.J1Slider.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.J1Slider.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @J1SliderValueChanged, true);
            app.J1Slider.MinorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180 195 210 225 240 255 270 285 300 315 330 345 360];
            app.J2SliderLabel = uilabel(app.JointPositionsPanel);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.JointPositionsPanel);
            app.J2Slider.Limits = [0 180];
            app.J2Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.J2Slider.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180'};
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J2Slider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.J3SliderLabel = uilabel(app.JointPositionsPanel);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.JointPositionsPanel);
            app.J3Slider.Limits = [0 180];
            app.J3Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.J3Slider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.VelocitySliderLabel = uilabel(app.JointPositionsPanel);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.JointPositionsPanel);
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.VelocitySlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.SearchSerialPortButton = uibutton(app.UIFigure, 'push');
            app.SearchSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @SearchSerialPortButtonPushed, true);
            app.SearchSerialPortButton.Text = 'Search Serial Port';
            app.ArduinonotconnectedLabel = uilabel(app.UIFigure);
            app.ArduinonotconnectedLabel.Text = 'Arduino not connected';
            app.RobotHomeButton = uibutton(app.UIFigure, 'push');
            app.RobotHomeButton.ButtonPushedFcn = createCallbackFcn(app, @RobotHomeButtonPushed, true);
            app.RobotHomeButton.Text = 'Robot Home';
            app.PressureControlPanel = uipanel(app.UIFigure);
            app.PressureControlPanel.Title = 'Pressure Control';
            app.VSL1SliderLabel = uilabel(app.PressureControlPanel);
            app.VSL1SliderLabel.Text = 'VSL 1';
            app.VSL1Slider = uislider(app.PressureControlPanel);
            app.VSL1Slider.Limits = [0 3];
            app.VSL1Slider.ValueChangedFcn = createCallbackFcn(app, @VSL1SliderValueChanged, true);
            app.VSL2Label = uilabel(app.PressureControlPanel);
            app.VSL2Label.Text = 'VSL 2';
            app.VSL2Slider = uislider(app.PressureControlPanel);
            app.VSL2Slider.Limits = [0 3];
            app.VSL2Slider.ValueChangedFcn = createCallbackFcn(app, @VSL2SliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pressure VSL')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Pressure [bar]')
            app.UIAxes.XTick = [0 1 2 3];
            app.UIAxes.XTickLabel = {'0'};
            app.UIAxes.YTick = [0 1 2 3];
            app.UIAxes.YTickLabel = {'0'};
            app.RecordingButton = uibutton(app.UIFigure, 'state');
            app.RecordingButton.ValueChangedFcn = createCallbackFcn(app, @RecordingButtonValueChanged, true);
            app.RecordingButton.Text = 'Recording';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotMonitor_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end